classdef ShockwaveApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GraphOverallResultsButton  matlab.ui.control.Button
        GraphRedResultsButton      matlab.ui.control.Button
        GraphGreenResultsButton    matlab.ui.control.Button
        AnalyzeCellsButton         matlab.ui.control.Button
        FindCellsButton            matlab.ui.control.Button
        LoadFilesButton            matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        size1 %Dimension 1 of Image Array
        size2 %Dimension 2 of Image Array
        size3 %Dimension 3 of Image Array
        
        cellNumber
        
        greenFileLocation = "-1" %File Directory for Fluo4 Images
        redFileLocation = "-1" %File Directory for Hcred Images
         
        greenImageInfo %Information About the Fluo4 Images Folder
        redImageInfo %Information About the Fluo4 Images Folder
        
        greenImages %Array of Fluo4 Image Data
        redImages %Array of Hcred Image Data
        
        shockwaveCenter = [444, 585];
        
        
        noiseReducedGreenCellImages %Fluo4 Cell Images After Applying Gaussian Kernal and Morphology
        noiseReducedRedCellImages %Hcred Cell Images After Applying Gaussian Kernal 
        
        thresholdedGreenCellImages %Fluo4 Cell Images After Thresholding the Images
        thresholdedRedCellImages %Hcred Cell Images After Thresholding the Images
        watershedCellImages %Array of Segemented Flor4 Cells
        
        greenCellBrightnesses %Array of the Brightnesses of Cells for Fluo4 
        redCellBrightnesses %Array of the Brightnesses of Cells for Hcred 
        
        cellCenters
        cellDistance
        
        overallResults
        
        greenThreshold = 225;
        redThreshold = 130;
         
    end
    
    methods (Access = private)
        
        function y = imageReduceNoise1(~, cellImage) %Reduce Noise on Flou4 Images
            cellImage = imgaussfilt(cellImage, 2);
            disk = strel("disk", 10);
            
            cellImageErode = imerode(cellImage, disk);
            cellImageMask1 = imreconstruct(cellImageErode, cellImage);
            cellImageMarker1 = imdilate(cellImageMask1, disk);
            
            y = imcomplement(imreconstruct(imcomplement(cellImageMarker1), imcomplement(cellImageMask1)));
            
        end
        
        function y = imageReduceNoise2(~, cellImage) %Reduce Noise on Hcred Images
            y = imgaussfilt(cellImage, 1/2);
        end
        
        function y = imageThreshold1(~, cellImage, threshold) %Threshold Flou4 Images
            Y = arrayfun(@(x) (x > threshold), cellImage);

            squareStrel = zeros(3,3) + 1;
            
            for i = 1:3
                Y = imerode(Y, squareStrel);
            end
            
            for i = 1:3
                y = imdilate(Y, squareStrel);
            end

        end
        
        function y = imageThreshold2(~, cellImage, threshold) %Threshold Hcred Images
            for i = 1:size(cellImage, 1)
                for j = 1:size(cellImage, 2)
                    if cellImage(i,j) < threshold
                        cellImage(i,j) = threshold;
                    end
                end
            end
            
            y = cellImage;
        end
        
        function y = regionalmax(~, cellImage)
            y = imregionalmax(cellImage);
        end
        
        function y = watershedRidge(~, cellImage)
            cellImage = imregionalmax(cellImage);
            cellImageBWDist = bwdist(~cellImage);
            y = watershed(~cellImageBWDist);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFilesButton
        function LoadFilesButtonPushed(app, event)
            try
                gMSG = msgbox("Please select the green folder.");
                app.greenFileLocation = uigetdir("C:");
                delete(gMSG);
                %app.greenFileLocation = "C:\Users\Justin\Justin Documents\UCSD MAP\Shockwave Images\fluo4";
                
                rMSG = msgbox("Please select the red folder.");
                app.redFileLocation = uigetdir("C:");
                delete(rMSG);
                %app.redFileLocation = "C:\Users\Justin\Justin Documents\UCSD MAP\Shockwave Images\hcred";
                
                app.greenImageInfo = dir(app.greenFileLocation + "\*.tif");
                app.redImageInfo = dir(app.redFileLocation + "\*.tif");
                
                for I = 1:size(app.greenImageInfo, 1)
                    app.greenImages(:,:,I) = imread(app.greenFileLocation + "\" +app.greenImageInfo(I).name);
                    %disp("green file name:" + app.greenImageInfo(I).name);
                end
                
                for I = 1:size(app.redImageInfo, 1)
                    app.redImages(:,:,I) = imread(app.redFileLocation + "\" + app.redImageInfo(I).name);
                    %disp("red file name:" + app.redImageInfo(I).name);
                end
                
                if app.greenFileLocation == "-1" || app.redFileLocation == "-1"
                    msgbox("Error selecting the folders.")
                else
                    msgbox("Successfully selected the folders.");
                    
                    pause(1);
                    close all;
                end
                
                app.size1 = size(app.greenImages, 1);
                app.size2 = size(app.greenImages, 2);
                app.size3 = size(app.greenImages, 3);           
                
            catch
                msgbox("Error selecting the folders.")
            end
            
        end

        % Button pushed function: FindCellsButton
        function FindCellsButtonPushed(app, event)
            msgbox("Finding Cells...", "Finding Cells");
            
            app.noiseReducedGreenCellImages = zeros(size(app.greenImages));
            app.noiseReducedRedCellImages = zeros(size(app.redImages));
            
            app.thresholdedGreenCellImages = zeros(size(app.greenImages));
            app.thresholdedRedCellImages = zeros(size(app.redImages));
            
            app.watershedCellImages = zeros(size(app.greenImages));
            
            %for i = 1:1
            for i = 1:app.size3
                app.noiseReducedGreenCellImages(:, :, i) = app.imageReduceNoise1(app.greenImages(:, :, i));
                app.noiseReducedRedCellImages(:, :, i) = app.imageReduceNoise2(app.redImages(:, :, i));
                
                app.thresholdedGreenCellImages(:, :, i) = app.imageThreshold1(app.noiseReducedGreenCellImages(:, :, i), app.greenThreshold);
                app.thresholdedRedCellImages(:, :, i) = app.imageThreshold2(app.noiseReducedRedCellImages(:, :, i), app.redThreshold);
                
                app.watershedCellImages(:, :, i) = app.watershedRidge(app.noiseReducedGreenCellImages(:, :, i));
                
                msgbox("Finding Cells... " + i + "/" + app.size3, "Finding Cells", "replace");
            end
            
            msgbox("Finished Finding Cells", "Finding Cells", "modal");
        end

        % Button pushed function: AnalyzeCellsButton
        function AnalyzeCellsButtonPushed(app, event)
            msgbox("Analyzing Cells...", "Analyzing Cells");
            
            app.cellNumber = max(app.watershedCellImages(:, :, :), [], 'all');
            
            app.greenCellBrightnesses = zeros(app.cellNumber, app.size3);
            app.redCellBrightnesses = zeros(app.cellNumber, app.size3);
            app.overallResults = zeros(app.cellNumber, app.size3);
%             app.cellCenters = zeros(app.cellNumber, 2, app.size3);
%             app.cellDistance = zeros(app.cellNumber, app.size3);

            
            for i = 1:app.size3
            %for i = 1:1
                for j = 1:app.cellNumber
                    cellSize = uint32(0);
                    cellTotalBrightness = uint32(0);
                    
                    cellMaxSize = uint32(0);
                    cellMaxTotal = uint32(0);
                    
                    for l = 1:app.size1
                        for k = 1:app.size2
                            if app.watershedCellImages(k, l, i) == j && app.thresholdedGreenCellImages(k, l, i) ~= 0
                                cellSize = cellSize + 1;
                                cellTotalBrightness = cellTotalBrightness + uint32(app.greenImages(k, l, i));
                            end
                            
                            if app.watershedCellImages(k, l, i) == j && app.thresholdedRedCellImages(k, l, i) ~= app.redThreshold
                                cellMaxSize = cellMaxSize + 1;
                                cellMaxTotal = cellMaxTotal + uint32(app.redImages(k, l, i));
                            end
                        end
                    end
                    
                    
%                     cellBoundaries = arrayfun(@(x) (x==j), app.watershedCellImages(:, :, i));
%                     cellCenter = round(regionprops(cellBoundaries,"centroid").Centroid);
%                     for l = 1:app.size1
%                         for dl = 1:app.size2
%                             if app.watershedCellImages(k, l, i)
%                                 cellCenter = [k, l];
%                                 break
%                             end
%                         end
%                     end
%                     app.cellCenters(j,:,i) = cellCenter;
%                     app.cellDistance (j, i) = sqrt((app.shockwaveCenter(1)-cellCenter(1))^2+(app.shockwaveCenter(2)-cellCenter(2))^2);
                    
                    app.greenCellBrightnesses(j, i) = double(cellTotalBrightness)/double(cellSize);
                    app.redCellBrightnesses(j, i) = double(cellMaxTotal)/double(cellMaxSize);
                    
                    app.overallResults(j, i) = app.redCellBrightnesses(j, i)/app.greenCellBrightnesses(j, i);
                    
                    msgbox("Analyzing Cells..."  + j + "/" + app.cellNumber + ", " + i + "/" + app.size3, "Analyzing Cells", "replace");
                end
                
                msgbox("Analyzing Cells..."  + i + "/" + app.size3, "Analyzing Cells", "replace");
            end
            
            msgbox("Finished Analyzing Cells", "Analyzing Cells", "modal");

            %imshow(app.thresholdedRedCellImages, 'parent', app.UIAxes);
        end

        % Button pushed function: GraphGreenResultsButton
        function GraphGreenResultsButtonPushed(app, event)
            cla(app.UIAxes)
            
            hold (app.UIAxes, 'on');
            
            msgbox("Graphing Flou4 Results...", "Graphing");

            writematrix(app.greenCellBrightnesses, "fluo4CellBrightness.csv");
            
            for i = 1:size(app.greenCellBrightnesses, 2)
                x = zeros(size(app.greenCellBrightnesses, 1)) + i;
                y = app.greenCellBrightnesses(:,i);
%                 z = app.cellDistance(:,i);
                
                %swarmchart(app.UIAxes, x, y);
                scatter(app.UIAxes, x, y);
            end
            
            app.UIAxes.xlabel('Time (Image #s)');
            app.UIAxes.ylabel('Intensities (Correlates to Calcium Levels)');
            app.UIAxes.title('Time vs Calcium Levels');
            
            colorbar
            
            msgbox("Finished Graphing Flou4 Results...", "Graphing", "modal");
            
            hold (app.UIAxes, 'off');
        end

        % Button pushed function: GraphRedResultsButton
        function GraphRedResultsButtonPushed(app, event)
            cla(app.UIAxes)
            
            hold (app.UIAxes, 'on');
            
            msgbox("Graphing Hcred Results...", "Graphing");
            
            writematrix(app.redCellBrightnesses, "hcredCellMaxes.csv");
            
            disp("graphing2");

            for i = 1:size(app.redCellBrightnesses, 2)
                x = zeros(size(app.redCellBrightnesses, 1)) + i;
                y = app.redCellBrightnesses(:,i);
%                  z = app.cellDistance(:,i);
                
                %swarmchart(app.UIAxes, x, y);
                scatter(app.UIAxes, x, y);
            end
            
            app.UIAxes.xlabel('Time (Image #s)');
            app.UIAxes.ylabel('Intensities (Correlates to Lysosome Levels)');
            app.UIAxes.title('Time vs Lysosome Levels');
            colorbar
            
            msgbox("Finished Graphing Hcred Results...", "Graphing", "modal");
            
            hold (app.UIAxes, 'off');
        end

        % Button pushed function: GraphOverallResultsButton
        function GraphOverallResultsButtonPushed(app, event)
            cla(app.UIAxes)
            
            hold (app.UIAxes, 'on');
            
            msgbox("Graphing Overall Results...", "Graphing");
            
            writematrix(app.overallResults, "overallShockwaveResults.csv");
            
            for i = 1:size(app.overallResults, 2)
                x = zeros(size(app.overallResults, 1)) + i;
                y = app.overallResults(:,i);
%                 z = app.cellDistance(:,i);
                
                %swarmchart(app.UIAxes, x, y);
                scatter(app.UIAxes, x, y);
            end
            
            app.UIAxes.xlabel('Time (Image #s)');
            app.UIAxes.ylabel('Lysosome Levels/Calcium Levels');
            app.UIAxes.title('Time vs Lysosome/Calcium Levels');
            colorbar
            
            msgbox("Finished Graphing Overall Results...", "Graphing", "modal");
            
            hold (app.UIAxes, 'off'); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.LoadFilesButton = uibutton(app.UIFigure, 'push');
            app.LoadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFilesButtonPushed, true);
            app.LoadFilesButton.Text = 'Load Files';
            app.FindCellsButton = uibutton(app.UIFigure, 'push');
            app.FindCellsButton.ButtonPushedFcn = createCallbackFcn(app, @FindCellsButtonPushed, true);
            app.FindCellsButton.Text = 'Find Cells';
            app.AnalyzeCellsButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeCellsButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeCellsButtonPushed, true);
            app.AnalyzeCellsButton.Text = 'Analyze Cells';
            app.GraphGreenResultsButton = uibutton(app.UIFigure, 'push');
            app.GraphGreenResultsButton.ButtonPushedFcn = createCallbackFcn(app, @GraphGreenResultsButtonPushed, true);
            app.GraphGreenResultsButton.Text = 'Graph Green Results';
            app.GraphRedResultsButton = uibutton(app.UIFigure, 'push');
            app.GraphRedResultsButton.ButtonPushedFcn = createCallbackFcn(app, @GraphRedResultsButtonPushed, true);
            app.GraphRedResultsButton.Text = 'Graph Red Results';
            app.GraphOverallResultsButton = uibutton(app.UIFigure, 'push');
            app.GraphOverallResultsButton.ButtonPushedFcn = createCallbackFcn(app, @GraphOverallResultsButtonPushed, true);
            app.GraphOverallResultsButton.Text = 'Graph Overall Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ShockwaveApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end